classdef transformador_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AjudaButton                     matlab.ui.control.Button
        CheckBoxText                    matlab.ui.control.CheckBox
        CheckBoxGraficos                matlab.ui.control.CheckBox
        EnsaiodeTranformadoresLabel     matlab.ui.control.Label
        TipodetransformadorButtonGroup  matlab.ui.container.ButtonGroup
        MonofasicoButton                matlab.ui.control.RadioButton
        TrifsicoButton                  matlab.ui.control.RadioButton
        CalcularButton                  matlab.ui.control.Button
        SNKVAEditField                  matlab.ui.control.NumericEditField
        SNKVAEditFieldLabel             matlab.ui.control.Label
        VNATKVEditField                 matlab.ui.control.NumericEditField
        VNATKVEditFieldLabel            matlab.ui.control.Label
        VNBTVEditField                  matlab.ui.control.NumericEditField
        VNBTVEditFieldLabel             matlab.ui.control.Label
        V0VEditField                    matlab.ui.control.NumericEditField
        V0VEditFieldLabel               matlab.ui.control.Label
        I0AEditField                    matlab.ui.control.NumericEditField
        I0AEditFieldLabel               matlab.ui.control.Label
        P0WEditField                    matlab.ui.control.NumericEditField
        P0WEditFieldLabel               matlab.ui.control.Label
        VccVEditField                   matlab.ui.control.NumericEditField
        VccVEditFieldLabel              matlab.ui.control.Label
        IccAEditField                   matlab.ui.control.NumericEditField
        IccAEditFieldLabel              matlab.ui.control.Label
        PCCWEditField                   matlab.ui.control.NumericEditField
        PCCWEditFieldLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Saida01Tab                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Title_01                        matlab.ui.control.Label
        Title_02                        matlab.ui.control.Label
        Out_01                          matlab.ui.control.Label
        Out_02                          matlab.ui.control.Label
        Title_03                        matlab.ui.control.Label
        Out_03                          matlab.ui.control.Label
        Saida02Tab                      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AjudaButton
        function AjudaButtonPushed(app, event)
            web("https://github.com/emanuel-alves/Trabalho_EELI16")
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            close all;
            if app.CheckBoxGraficos.Value
                t = 0:0.001:4*pi;
                plot(t, sin(t))
            end
            if app.CheckBoxText.Value
                app.Out_01.Text = 'Output 01'
                app.Out_02.Text = 'Output 002'
                app.Out_03.Text = 'Output 0003'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AjudaButton = uibutton(app.UIFigure, 'push');
            app.AjudaButton.ButtonPushedFcn = createCallbackFcn(app, @AjudaButtonPushed, true);
            app.AjudaButton.Text = 'Ajuda';
            app.CheckBoxText = uicheckbox(app.UIFigure);
            app.CheckBoxText.Text = 'Gerar textos de saida';
            app.CheckBoxText.Value = true;
            app.CheckBoxGraficos = uicheckbox(app.UIFigure);
            app.CheckBoxGraficos.Text = 'Gerar Gráficos';
            app.CheckBoxGraficos.Value = true;
            app.EnsaiodeTranformadoresLabel = uilabel(app.UIFigure);
            app.EnsaiodeTranformadoresLabel.Text = 'Ensaio de Tranformadores';
            app.TipodetransformadorButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodetransformadorButtonGroup.Title = 'Tipo de transformador';
            app.MonofasicoButton = uiradiobutton(app.TipodetransformadorButtonGroup);
            app.MonofasicoButton.Text = 'Monofasico';
            app.MonofasicoButton.Value = true;
            app.TrifsicoButton = uiradiobutton(app.TipodetransformadorButtonGroup);
            app.TrifsicoButton.Text = 'Trifásico';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.SNKVAEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNKVAEditFieldLabel = uilabel(app.UIFigure);
            app.SNKVAEditFieldLabel.Text = 'SN [KVA]';
            app.VNATKVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VNATKVEditFieldLabel = uilabel(app.UIFigure);
            app.VNATKVEditFieldLabel.Text = 'VNAT[KV]';
            app.VNBTVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VNBTVEditFieldLabel = uilabel(app.UIFigure);
            app.VNBTVEditFieldLabel.Text = 'VNBT [V]';
            app.V0VEditField = uieditfield(app.UIFigure, 'numeric');
            app.V0VEditFieldLabel = uilabel(app.UIFigure);
            app.V0VEditFieldLabel.Text = 'V0 [V]';
            app.I0AEditField = uieditfield(app.UIFigure, 'numeric');
            app.I0AEditFieldLabel = uilabel(app.UIFigure);
            app.I0AEditFieldLabel.Text = 'I0 [A]';
            app.P0WEditField = uieditfield(app.UIFigure, 'numeric');
            app.P0WEditFieldLabel = uilabel(app.UIFigure);
            app.P0WEditFieldLabel.Text = 'P0 [W]';
            app.VccVEditField = uieditfield(app.UIFigure, 'numeric');
            app.VccVEditFieldLabel = uilabel(app.UIFigure);
            app.VccVEditFieldLabel.Text = {'Vcc [V]'; ''};
            app.IccAEditField = uieditfield(app.UIFigure, 'numeric');
            app.IccAEditFieldLabel = uilabel(app.UIFigure);
            app.IccAEditFieldLabel.Text = 'Icc [A]';
            app.PCCWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCCWEditFieldLabel = uilabel(app.UIFigure);
            app.PCCWEditFieldLabel.Text = 'PCC [W]';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Saida01Tab = uitab(app.TabGroup);
            app.Saida01Tab.Title = 'Saida 01';
            app.GridLayout = uigridlayout(app.Saida01Tab);
            app.Title_01 = uilabel(app.GridLayout);
            app.Title_01.Text = 'S1:';
            app.Title_02 = uilabel(app.GridLayout);
            app.Title_02.Text = 'S2:';
            app.Out_01 = uilabel(app.GridLayout);
            app.Out_01.Text = '-';
            app.Out_02 = uilabel(app.GridLayout);
            app.Out_02.Text = '-';
            app.Title_03 = uilabel(app.GridLayout);
            app.Title_03.Text = 'S3:';
            app.Out_03 = uilabel(app.GridLayout);
            app.Out_03.Text = '-';
            app.Saida02Tab = uitab(app.TabGroup);
            app.Saida02Tab.Title = 'Saida 02';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformador_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end